classdef DualSliderWidgetStagingApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainFigure      matlab.ui.Figure
        MainLayout      matlab.ui.container.GridLayout
        DualSlider      wt.DualSlider
        SliderSpinner   wt.SliderSpinner
        MWSliderLayout  matlab.ui.container.GridLayout
        Slider          matlab.ui.control.Slider
        DisplayAxis     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)

        % - MW Slider Plot Properties
        SliderPlot (1,1) = gobjects(1,1);
        SliderX (:,1) = 1:100;
        SliderY (:,1) = 50 + 10 * rand([100,1]);

        % - Dual Slider Plot Handle
        DualSliderPlots (2,1) = gobjects(2,1);
        DualSliderValues (2,1) double = [50;50];

    end


    methods (Access = private)
        
        function gUpdate(app)
            % GUPDATE - Updates the graphics objects in the app.
            %  Call this to update any images drawn to the main axis.

            % Get Changing Value
            app.DualSliderValues = app.DualSlider.Value;
    
            % Reset Dual Slider Plot Values
            for i = 1:2
                set(app.DualSliderPlots(i),...
                    "Value",app.DualSliderValues(i));
            end 

            % Masek Color
            maskColor = zeros(100,3);

            for i = 1:100
                if (i > app.DualSliderValues(1) && i < app.DualSliderValues(2))
                    maskColor(i,:) = [0,1,0];
                else
                    maskColor(i,:) = [1,0,0];
                end
            end

            set(app.SliderPlot,"CData",maskColor);

            % Force Graphics Update
            drawnow;

        end %function

    end %methods
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % -- Center FIgure
            movegui(app.MainFigure,"center");
            
            % - Initialize Plot Parameters
            % Slider Point Plot
            app.SliderPlot = scatter(app.DisplayAxis,...
                app.SliderX, app.SliderY, 10, "red");
            

            % Dual-Slider Boundary Plot
            for i = 1:2
                % Update Both Dual Slider Plots
                app.DualSliderPlots(i) = xline(app.DisplayAxis,...
                    app.DualSliderValues(i),...
                    "Color","k");
            end
            
            % Run GUpdate
            app.gUpdate();

            % Output App to workspace for debugging.
            assignin("base","app",app);           
            
            
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            % SliderValueChanging: Triggers when slider value is changing.
            %  Use this method to live-update the axis point positions
            % Store new Value into Property

            % Run Graphics Update
            app.gUpdate();
            
        end

        % Callback function: DualSlider
        function DualSliderValueChanging(app, event)
            % Triggers on Value Changed (Slider Thumb)
            disp("Slider value is changing");

            % Update Graphics
            app.gUpdate();
            
        end

        % Callback function: SliderSpinner
        function SliderSpinnerValueChanged(app, event)
            value = app.SliderSpinner.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainFigure = uifigure('Visible', 'off');
            app.MainFigure.Name = 'MATLAB App';
            app.MainLayout = uigridlayout(app.MainFigure);
            app.DisplayAxis = uiaxes(app.MainLayout);
            title(app.DisplayAxis, 'Slider Control Test Axis')
            xlabel(app.DisplayAxis, 'X')
            ylabel(app.DisplayAxis, 'Y')
            zlabel(app.DisplayAxis, 'Z')
            app.DisplayAxis.XLim = [0 100];
            app.DisplayAxis.YLim = [0 100];
            app.MWSliderLayout = uigridlayout(app.MainLayout);
            app.Slider = uislider(app.MWSliderLayout);
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.Slider.Value = 40;
            app.SliderSpinner = wt.SliderSpinner(app.MainLayout);
            app.SliderSpinner.ValueChangedFcn = createCallbackFcn(app, @SliderSpinnerValueChanged, true);
            app.DualSlider = wt.DualSlider(app.MainLayout);
            app.DualSlider.Value = [10 20];
            app.DualSlider.Limits = [0 200];
            app.DualSlider.ValueChangingFcn = createCallbackFcn(app, @DualSliderValueChanging, true);
            % Show the figure after all components are created
            app.MainFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DualSliderWidgetStagingApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainFigure)
        end
    end
end